classdef game < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MonopolyInMatlabUIFigure        matlab.ui.Figure
        propertiesLabel                 matlab.ui.control.Label
        Player4Label                    matlab.ui.control.Label
        Player3Label                    matlab.ui.control.Label
        EndTurnButton                   matlab.ui.control.Button
        Panel                           matlab.ui.container.Panel
        YouvegonebankruptLabel          matlab.ui.control.Label
        UseGetoutofjailfreecardButton   matlab.ui.control.Button
        OptionsLabel                    matlab.ui.control.Label
        Player2Label                    matlab.ui.control.Label
        Player1Label                    matlab.ui.control.Label
        CurrentSpotOptionsLabel         matlab.ui.control.Label
        Image3_4                        matlab.ui.control.Image
        Image3_3                        matlab.ui.control.Image
        Image3_2                        matlab.ui.control.Image
        Image3                          matlab.ui.control.Image
        BuyahouseButton                 matlab.ui.control.Button
        BuyahotelButton                 matlab.ui.control.Button
        Image2                          matlab.ui.control.Image
        BuyPropertyHereButton           matlab.ui.control.Button
        SellAPropertyButton             matlab.ui.control.Button
        LeavegameButton                 matlab.ui.control.Button
        CurrentTurnGamenotstartedLabel  matlab.ui.control.Label
        YourPropertiesLabel             matlab.ui.control.Label
        YourFunds100Label               matlab.ui.control.Label
        PlayersConnectedLabel           matlab.ui.control.Label
        Image                           matlab.ui.control.Image
        TextArea                        matlab.ui.control.TextArea
        ChatEditField                   matlab.ui.control.EditField
    end

    
    properties (Access = private)
        GameInstance % Description
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, roomId, userIsHost, localPlayerId)
            import Game.*;
            import StompClient.*;
            
            app.GameInstance = Game.gameInstance(app, roomId, userIsHost, localPlayerId);
            app.GameInstance.setStompClient(StompClient.getInstance());
        end

        % Button pushed function: BuyahouseButton
        function BuyahouseButtonPushed(app, event)
            if(app.Panel.Visible == "on")
                app.Panel.Visible = "off";
            else
                app.Panel.Visible = "on";
            end
        end

        % Button pushed function: LeavegameButton
        function LeavegameButtonPushed(app, event)
            delete(app);
            main_menu();
        end

        % Window key press function: MonopolyInMatlabUIFigure
        function MonopolyInMatlabUIFigureWindowKeyPress(app, event)

            if(strcmp(event.Key, "return"))
                focusedComponent = app.MonopolyInMatlabUIFigure.CurrentObject;

                if((focusedComponent == app.ChatEditField) && ~isempty(app.ChatEditField.Value))
                    app.GameInstance.sendChatMessage(app.ChatEditField.Value);
                    app.ChatEditField.Value = "";
                end
            end
            
        end

        % Button pushed function: EndTurnButton
        function EndTurnButtonPushed(app, event)
            switch(app.EndTurnButton.Text)
                case "Start Game"
                    if(app.GameInstance.weAreHost == true)
                        app.GameInstance.requestStartGame();
                    end
                
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MonopolyInMatlabUIFigure = uifigure('Visible', 'off');
            app.MonopolyInMatlabUIFigure.Name = 'Monopoly In Matlab';
            app.MonopolyInMatlabUIFigure.WindowKeyPressFcn = createCallbackFcn(app, @MonopolyInMatlabUIFigureWindowKeyPress, true);
            app.ChatEditField = uieditfield(app.MonopolyInMatlabUIFigure, 'text');
            app.ChatEditField.Placeholder = 'Enter message';
            app.TextArea = uitextarea(app.MonopolyInMatlabUIFigure);
            app.TextArea.Editable = 'off';
            app.Image = uiimage(app.MonopolyInMatlabUIFigure);
            app.Image.ImageSource = 'monopoly_board.jpg';
            app.PlayersConnectedLabel = uilabel(app.MonopolyInMatlabUIFigure);
            app.PlayersConnectedLabel.Text = 'Players Connected(N/A): ';
            app.YourFunds100Label = uilabel(app.MonopolyInMatlabUIFigure);
            app.YourFunds100Label.Text = 'Your Funds: $100';
            app.YourPropertiesLabel = uilabel(app.MonopolyInMatlabUIFigure);
            app.YourPropertiesLabel.Text = 'Your Properties:';
            app.CurrentTurnGamenotstartedLabel = uilabel(app.MonopolyInMatlabUIFigure);
            app.CurrentTurnGamenotstartedLabel.Text = 'Current Turn: Game not started';
            app.LeavegameButton = uibutton(app.MonopolyInMatlabUIFigure, 'push');
            app.LeavegameButton.ButtonPushedFcn = createCallbackFcn(app, @LeavegameButtonPushed, true);
            app.LeavegameButton.Text = 'Leave game';
            app.SellAPropertyButton = uibutton(app.MonopolyInMatlabUIFigure, 'push');
            app.SellAPropertyButton.Enable = 'off';
            app.SellAPropertyButton.Text = 'Sell A Property';
            app.BuyPropertyHereButton = uibutton(app.MonopolyInMatlabUIFigure, 'push');
            app.BuyPropertyHereButton.Enable = 'off';
            app.BuyPropertyHereButton.Text = 'Buy Property Here';
            app.Image2 = uiimage(app.MonopolyInMatlabUIFigure);
            app.Image2.ImageSource = 'hotel.png';
            app.BuyahotelButton = uibutton(app.MonopolyInMatlabUIFigure, 'push');
            app.BuyahotelButton.Enable = 'off';
            app.BuyahotelButton.Text = 'Buy a hotel';
            app.BuyahouseButton = uibutton(app.MonopolyInMatlabUIFigure, 'push');
            app.BuyahouseButton.ButtonPushedFcn = createCallbackFcn(app, @BuyahouseButtonPushed, true);
            app.BuyahouseButton.Enable = 'off';
            app.BuyahouseButton.Text = 'Buy a house';
            app.Image3 = uiimage(app.MonopolyInMatlabUIFigure);
            app.Image3.ImageSource = 'house.png';
            app.Image3_2 = uiimage(app.MonopolyInMatlabUIFigure);
            app.Image3_2.ImageSource = 'house.png';
            app.Image3_3 = uiimage(app.MonopolyInMatlabUIFigure);
            app.Image3_3.ImageSource = 'house.png';
            app.Image3_4 = uiimage(app.MonopolyInMatlabUIFigure);
            app.Image3_4.ImageSource = 'house.png';
            app.CurrentSpotOptionsLabel = uilabel(app.MonopolyInMatlabUIFigure);
            app.CurrentSpotOptionsLabel.Enable = 'off';
            app.CurrentSpotOptionsLabel.Text = 'Current Spot Options';
            app.Player1Label = uilabel(app.MonopolyInMatlabUIFigure);
            app.Player1Label.Text = '';
            app.Player2Label = uilabel(app.MonopolyInMatlabUIFigure);
            app.Player2Label.Text = '';
            app.OptionsLabel = uilabel(app.MonopolyInMatlabUIFigure);
            app.OptionsLabel.Enable = 'off';
            app.OptionsLabel.Text = 'Options';
            app.UseGetoutofjailfreecardButton = uibutton(app.MonopolyInMatlabUIFigure, 'push');
            app.UseGetoutofjailfreecardButton.Enable = 'off';
            app.UseGetoutofjailfreecardButton.Text = 'Use Get out of jail free card';
            app.Panel = uipanel(app.MonopolyInMatlabUIFigure);
            app.YouvegonebankruptLabel = uilabel(app.Panel);
            app.YouvegonebankruptLabel.Text = 'Youve gone bankrupt!';
            app.EndTurnButton = uibutton(app.MonopolyInMatlabUIFigure, 'push');
            app.EndTurnButton.ButtonPushedFcn = createCallbackFcn(app, @EndTurnButtonPushed, true);
            app.EndTurnButton.Text = 'End Turn';
            app.Player3Label = uilabel(app.MonopolyInMatlabUIFigure);
            app.Player3Label.Text = '';
            app.Player4Label = uilabel(app.MonopolyInMatlabUIFigure);
            app.Player4Label.Text = '';
            app.propertiesLabel = uilabel(app.MonopolyInMatlabUIFigure);
            app.propertiesLabel.Text = '';
            % Show the figure after all components are created
            app.MonopolyInMatlabUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = game(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MonopolyInMatlabUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MonopolyInMatlabUIFigure)
        end
    end
end